classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        WeightSensorTurbine   matlab.ui.control.Lamp
        WeightMonitorTurbine  matlab.ui.control.NumericEditField
        TurbineSensor2        matlab.ui.control.Lamp
        TurbineSensor3        matlab.ui.control.Lamp
        WeightMonitorBox      matlab.ui.control.NumericEditField
        WeightSensorBox       matlab.ui.control.Image
        MonitorA              matlab.ui.control.NumericEditField
        MonitorB              matlab.ui.control.NumericEditField
        WeightBalancerA       matlab.ui.control.Spinner
        WeightBalancerB       matlab.ui.control.Spinner
        CloserA               matlab.ui.control.Slider
        CloserB               matlab.ui.control.Slider
    end


    properties (Access = public)
        %% Oggetti
        boxes;
        turbines;

        %% Timer
        masterTimer;
        spawnTimer;
        boxAnimationTimer;
        turbineAnimationTimer;

        %% Modbus
        mb;
        plc_connected = false;

        %% Labels
        types = ["A", "B", "C", "NotValid"];

        %% Variables
        boxVelocity = 10;
        boxWeight;
        toAddA;
        toAddB;
        lineABlocked = false;
        lineBBlocked = false;
    end

    methods (Access = public)
        %% Master Loop
        % Funzionamento: ad ogni ciclo di masterTimer viene richiamata la
        % funzione update su tutti gli oggetti presenti in scena. Gli
        % ogggetti sono generati con logica e timing separato gestito dallo
        % spawnTimer.
        function masterLoop(app)
            if app.plc_connected
                try
                    % Scorro su tutti gli oggetti dell'array objects
                    for i = length(app.boxes):-1:1
                        box = app.boxes{i};
                        app.updateBoxes(box);
                        if box.shouldDelete
                            delete(box.graphic);
                            app.boxes(i) = [];
                            disp(app.boxes)
                        end
                    end

                    for i = length(app.turbines):-1:1
                        turbine = app.turbines{i};
                        app.updateTurbines(turbine);
                        if turbine.shouldDelete
                            delete(turbine.graphic);
                            app.turbines(i) = [];
                            disp(app.turbines)
                        end
                    end
                catch e
                    disp(['Errore lettura coil: ' e.message]);
                end
            end
        end

        %% Funzioni per generare gli oggetti
        % Usata da SpawnTimer, richiama tutti i metodi di generazione
        function spawnObjects(app)
            app.spawnBox()
            app.spawnTurbine()
        end

        function spawnBox(app)
            % Tipo casuale
            value = rand;
            if value < 0.5
                weight = 920 + randi([0 8]) * 20;
                color = [1 0 0];
            elseif value > 0.5
                weight = 1280 + randi([0 8]) * 30;
                color = [0 1 0];
            elseif value > 2 % Bulloni tipo C, per ora mai generati
                weight = 1800 + randi([0 8]) * 50;
                color = [0 0 1];
            end

            % Creazione grafica
            objGraphic = uilabel(app.UIFigure);
            objGraphic.BackgroundColor = color;
            objGraphic.Position = [-20 400 40 40];

            % Creazione oggetto logico
            obj = IndustrialObject(weight, objGraphic);

            % Salvataggio nell'array
            app.boxes{end+1} = obj;
        end

        function spawnTurbine(app)
            weight = 3000 + randi(500);
            color = [0 0 1];

            % Creazione grafica
            objGraphic = uilabel(app.UIFigure);
            objGraphic.BackgroundColor = color;
            objGraphic.Position = [500 550 40 40];

            % Creazione oggetto logico
            obj = IndustrialObjectType2(weight, objGraphic);

            % Salvataggio nell'array
            app.turbines{end+1} = obj;
        end
        %% Spostamenti di tutti gli oggetti (forse conviene metterlo dentro la classe IndustrialObject?)
        % Aggiornamento delle box: prima si valuta il type (in questa funzione), dopo si lavora
        % guardando lo state (in updateA, updateB e updateC)
        function updateBoxes(app, obj)
            % Controllo basato sul tipo (Non categorizzato, A//B/C)
            switch obj.type
                % Mi dirigo verso il sensore di peso
                case "NotClassified"
                    obj.move(app.boxVelocity, 0)
                    if obj.getX() > app.WeightSensorBox.Position(1) && obj.getX() < app.WeightSensorBox.Position(1) + 11
                        write(app.mb, "holdingregs", 1, obj.getWeight());
                        app.WeightMonitorBox.Value = obj.getWeight();
                        obj.setType("WaitingBalanceAndClassification");
                    end
                    % Sono sul sensore di peso: attendo la classificazione
                case "WaitingBalanceAndClassification"
                    if obj.waitingTime > 5
                        boxType = read(app.mb, "holdingregs", 2, 1);
                        obj.setType(app.types(boxType));
                        obj.setState("Classified")
                        obj.waitingTime = 0;
                    else
                        obj.waitingTime=obj.waitingTime+1;
                    end

                case "A"
                    app.updateA(obj);

                case "B"
                    app.updateB(obj);

                case "C"
                    % Si può aggiungere un terzo oggetto;
            end
        end
        % La funzione lineABlocked impone che se un oggetto si trova nello
        % stato di modifing,lineABlocked risulta true, quindi la catena A si ferma,

        % Aggiornamento delle box di tipo A
        function updateA(app, obj)
            switch obj.state
                case "Classified" % Appena classificato, modifico il valore del monitor e dirigo la scatola verso di esso
                    raw = read(app.mb, "holdingregs", 3, 1);
                    isNegative = int16(bitget(raw, 16));
                    app.toAddA = int16(bitset(raw, 16, 0)) + (-2^15) * isNegative;
                    app.MonitorA.Value = double(app.toAddA);
                    obj.setState("MovingToMonitor");

                case "MovingToMonitor" % Muovo la scatola verso il monitor, deve arrivarci prima che un altro oggetto dello stesso tipo sia pesato (per semplicità)
                    if obj.getX() < app.MonitorA.Position(1) + 2*app.MonitorA.Position(3) % Se sono al monitor e ...
                        if ~app.lineABlocked    % ... se la linea è libera, il balancer si prepara
                            if app.WeightBalancerA.Value < app.toAddA
                                app.WeightBalancerA.Value = app.WeightBalancerA.Value + 1;
                            elseif app.WeightBalancerA.Value > app.toAddA
                                app.WeightBalancerA.Value = app.WeightBalancerA.Value - 1;
                            else    % Una volta pronto, l'oggetto viene mandato al balancer
                                obj.setState("MovingToBalancer");
                            end
                        end
                    else
                        obj.move(-2*app.boxVelocity, -app.boxVelocity); % Altrimenti vado verso il monitor
                    end

                case "MovingToBalancer"
                    obj.move(0, -app.boxVelocity);% Vado verso il Balancer e quando arrivo blocco tutto
                    if obj.getY() < app.WeightBalancerA.Position(2)
                        app.lineABlocked = true;
                        obj.setState("Balancing");
                    end

                case "Balancing"   % Bilancio e libero la linea per i prossimi oggetti
                    if obj.waitingTime > 5
                        app.WeightBalancerA.Value = 0;
                        app.lineABlocked = false;
                        obj.setState("MovingToCloser");
                        obj.waitingTime = 0;
                    else
                        obj.waitingTime = obj.waitingTime + 1;
                    end

                case "MovingToCloser"
                    obj.move(0, -app.boxVelocity);
                    if obj.getY() < app.CloserA.Position(2)
                        obj.setState("Closing");
                    end

                case "Closing"
                    app.CloserA.Value = app.CloserA.Value + 5;
                    if app.CloserA.Value == 50
                        app.CloserA.Value = 0;
                        obj.setState("Finished");
                    end

                case "Finished"
                    obj.move(0, -app.boxVelocity);
                    if obj.getY() < 0
                        obj.shouldDelete = true;
                    end
            end
        end

        % Aggiornamento delle box di tipo B
        function updateB(app, obj)
            switch obj.state
                case "Classified" % Appena classificato, modifico il valore del monitor e dirigo la scatola verso di esso
                    raw = read(app.mb, "holdingregs", 4, 1);
                    isNegative = int16(bitget(raw, 16));
                    app.toAddB = int16(bitset(raw, 16, 0)) + (-2^15) * isNegative;
                    app.MonitorB.Value = double(app.toAddB);
                    obj.setState("MovingToMonitor");

                case "MovingToMonitor" % Muovo la scatola verso il monitor, deve arrivarci prima che un altro oggetto dello stesso tipo sia pesato (per semplicità)
                    obj.move(0, -app.boxVelocity); % Arrivato al monitor ...
                    if obj.getY() < app.MonitorB.Position(2)
                        if ~app.lineBBlocked    % ... se la linea è libera il balancer si prepara
                            if app.WeightBalancerB.Value < app.toAddB
                                app.WeightBalancerB.Value = app.WeightBalancerB.Value + 1;
                            elseif app.WeightBalancerB.Value > app.toAddB
                                app.WeightBalancerB.Value = app.WeightBalancerB.Value - 1;
                            else    % Una volta pronto, l'oggetto viene mandato al balancer
                                obj.setState("MovingToBalancer");
                            end
                        end
                    end

                case "MovingToBalancer"
                    obj.move(0, -app.boxVelocity);% Vado verso il Balancer e quando arrivo blocco tutto
                    if obj.getY() < app.WeightBalancerB.Position(2)
                        app.lineBBlocked = true;
                        obj.setState("Balancing");
                    end

                case "Balancing"     % Bilancio e libero la linea per i prossimi oggetti
                    if obj.waitingTime > 10
                        app.WeightBalancerB.Value = 0;
                        app.lineBBlocked = false;
                        obj.setState("MovingToCloser");
                        obj.waitingTime = 0;
                    else
                        obj.waitingTime = obj.waitingTime + 1;
                    end

                case "MovingToCloser"
                    obj.move(0, -app.boxVelocity);
                    if obj.getY() < app.CloserB.Position(2)
                        obj.setState("Closing");
                    end

                case "Closing"
                    app.CloserB.Value = app.CloserB.Value + 5;
                    if app.CloserB.Value == 50
                        app.CloserB.Value = 0;
                        obj.setState("Finished");
                    end

                case "Finished"
                    obj.move(0,-app.boxVelocity);
                    if obj.getY() < 0
                        obj.shouldDelete = true;
                    end
            end
        end

        % Aggiornamento di tutte le turbine
        function updateTurbines(app, obj)
            switch obj.state
                case "Entered"
                    obj.move(0,-app.boxVelocity);
                    if obj.getY() < app.WeightSensorTurbine.Position(2) - 10
                        write(app.mb, "holdingregs", 6, obj.getWeight());
                        app.WeightMonitorTurbine.Value = obj.getWeight();
                        obj.setState("WaitingBalanceAndClassification");
                    end

                case "WaitingBalanceAndClassification"
                    if obj.waitingTime > 10
                        valid = read(app.mb, "coils", 1, 1);
                        if valid
                            obj.setState("MoveToSensor2")
                            app.WeightSensorTurbine.Color = [0 1 0];
                        else
                            obj.setState("Discarded")
                            app.WeightSensorTurbine.Color = [1 0 0];
                        end
                        obj.waitingTime = 0;
                    else
                        obj.waitingTime=obj.waitingTime+1;
                    end

                case "MoveToSensor2"
                    obj.move(0,-app.boxVelocity);
                    if obj.getY() < app.TurbineSensor2.Position(2) - 10
                        obj.setState("WaitingForSensor2");
                    end

                case "WaitingForSensor2"
                    obj.setState("MoveToSensor3")
                    % if obj.waitingTime > 10
                    %     % Per ora non esegue alcun controllo reale
                    %     valid = true;
                    %     if valid
                    %         obj.setState("MoveToSensor3")
                    %         app.TurbineSensor2.Color = [0 1 0];
                    %     else
                    %         app.TurbineSensor2.Color = [1 0 0];
                    %         obj.setState("Discarded")
                    %     end
                    %     obj.waitingTime = 0;
                    % else
                    %     obj.waitingTime=obj.waitingTime+1;
                    % end

                case "MoveToSensor3"
                    obj.move(0,-app.boxVelocity);
                    if obj.getY() < app.TurbineSensor3.Position(2) - 10
                        obj.setState("WaitingForSensor3");
                    end

                case "WaitingForSensor3"
                    obj.setState("Finished")
                    % if obj.waitingTime > 10
                    %     % Per ora non esegue alcun controllo reale
                    %     valid = true;
                    %     if valid
                    %         obj.setState("Finished")
                    %         app.TurbineSensor3.Color = [0 1 0];
                    %     else
                    %         app.TurbineSensor3.Color = [1 0 0];
                    %         obj.setState("Discarded")
                    %     end
                    %     obj.waitingTime = 0;
                    % else
                    %     obj.waitingTime=obj.waitingTime+1;
                    % end

                case "Finished"
                    obj.move(0,-app.boxVelocity);
                    if obj.getY() < 0
                        obj.shouldDelete = true;
                    end
                case "Discarded"
                    obj.move(app.boxVelocity, 0);
                    if obj.getX() > 600
                        obj.shouldDelete = true;
                    end
            end
        end
        %% Utilities
        % Funzioni per leggere e scrivere un valore reale da openplc
        function value = readReal(app, address)
            regs = read(app.mb, "holdingregs", address, 2);
            u32 = bitshift(uint32(regs(1)),16) + uint32(regs(2));
            value = typecast(u32,'single');
        end
        function writeReal(app, address, value)
            u32 = typecast(single(value), 'uint32');
            regs = double([ ...
                bitshift(u32,-16), ...
                bitand(u32,65535)
                ]);
            write(app.mb, "holdingregs", address, regs);
        end

        % Funzione per aggiornamento grafico graduale
        function updateValue(app)
            current = app.WeightMonitorBox.Value;
            target = app.boxWeight;
            if abs(target - current) < 1
                app.WeightMonitorBox.Value = target;
                stop(app.boxAnimationTimer);
                return;
            end

            app.WeightMonitorBox.Value = current + (target - current) * 0.2;
        end

        %% Start Simulation
        function startSimulation(app)
            if ~app.plc_connected
                try
                    app.mb = modbus('tcpip', '127.0.0.1', 502);
                    app.plc_connected = true;
                catch e
                    disp(e.message)
                    app.plc_connected = false;
                end
            end
        end

        %% Stop simulation
        function stopSimulation(app)
            % Ferma il master timer
            if ~isempty(app.masterTimer)
                stop(app.masterTimer);
            end
            % Chiudi connessione modbus
            if app.plc_connected && ~isempty(app.mb)
                try
                    delete(app.mb)
                catch
                end
                app.plc_connected = false;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startSimulation(app);
            if isempty(app.masterTimer) || ~isvalid(app.masterTimer)
                app.masterTimer = timer( ...
                    'ExecutionMode','fixedSpacing', ...
                    'Period', 0.1, ...
                    'BusyMode','drop', ...
                    'TimerFcn',@(~,~) app.masterLoop());
            end

            app.boxes = {};

            app.spawnTimer = timer;
            app.spawnTimer.ExecutionMode = 'fixedSpacing';
            app.spawnTimer.Period = 3;
            app.spawnTimer.TimerFcn = @(~,~) app.spawnObjects();
            app.spawnTimer.ErrorFcn = @(~,e) disp(e.Data);
            app.masterTimer.ErrorFcn = @(~,e) disp(e.Data);

            % app.boxAnimationTimer = timer;
            % app.boxAnimationTimer.ExecutionMode = 'fixedRate';
            % app.boxAnimationTimer.Period = 0.02;
            % app.boxAnimationTimer.TimerFcn = @(~,~) updateValue(app);


            if strcmp(app.masterTimer.Running,'off')
                start(app.masterTimer);
                start(app.spawnTimer);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CloserB = uislider(app.UIFigure);
            app.CloserB.Limits = [0 50];
            app.CloserA = uislider(app.UIFigure);
            app.CloserA.Limits = [0 50];
            app.WeightBalancerB = uispinner(app.UIFigure);
            app.WeightBalancerA = uispinner(app.UIFigure);
            app.MonitorB = uieditfield(app.UIFigure, 'numeric');
            app.MonitorA = uieditfield(app.UIFigure, 'numeric');
            app.WeightSensorBox = uiimage(app.UIFigure);
            app.WeightSensorBox.ImageSource = fullfile(pathToMLAPP, 'assets', 'sensore di peso.png');
            app.WeightMonitorBox = uieditfield(app.UIFigure, 'numeric');
            app.WeightMonitorBox.Placeholder = 'G';
            app.TurbineSensor3 = uilamp(app.UIFigure);
            app.TurbineSensor2 = uilamp(app.UIFigure);
            app.WeightMonitorTurbine = uieditfield(app.UIFigure, 'numeric');
            app.WeightMonitorTurbine.Placeholder = 'G';
            app.WeightSensorTurbine = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end